classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        SimpanButton            matlab.ui.control.Button
        HasilSalahButton        matlab.ui.control.StateButton
        KeluarButton            matlab.ui.control.Button
        TulisNamaBuahEditField  matlab.ui.control.EditField
        HasilPencocokanButton   matlab.ui.control.Button
        EkstraksiHasilButton    matlab.ui.control.Button
        SegmentasiButton        matlab.ui.control.Button
        PilihGambarButton       matlab.ui.control.StateButton
        RightPanel              matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        TextArea                matlab.ui.control.TextArea
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        file
        a
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PilihGambarButton
        function PilihGambarButtonValueChanged(app, event)
            % hObject    handle to SELECT_FOLDER (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
                    [app.file,~]=uigetfile({'*.jpg';'*.bmp';'*.gif';'*.tiff'}, 'Select file');
                    app.a= imread(app.file);
                    imshow(app.a, 'parent', app.UIAxes);
                    app.PilihGambarButton.Text="Reset";
                    app.SegmentasiButton.Enable ="on";
                    app.TextArea.Visible = "off";
                    app.EkstraksiHasilButton.Enable ="off";
                    app.HasilPencocokanButton.Enable ="off";
                    app.HasilSalahButton.Enable ="off";
                catch
                    errordlg('Gambar tidak terbaca!(Gambar harus ditaruh difolder project ini!)','Error');
                    app.a= 0;
            end
            if app.a == 0
                    app.PilihGambarButton.Text="Pilih Gambar";
                    imshow(zeros(128,128,3),'Parent',app.UIAxes)
            end
        end

        % Button pushed function: SegmentasiButton
        function SegmentasiButtonPushed(app, event)
            try 
                
                if app.a == 0
                    errordlg('Data belum dipilih!','Error');
                else
                    app.SegmentasiButton.Enable = "off";
                    I = app.a;
                    save('data.mat','I')
                    Segmentasi;
                    load('data.mat', 'I_bw');
                    imshow(I_bw,'Parent',app.UIAxes);
                    app.EkstraksiHasilButton.Enable ="on";
                    
                end
            catch
                errordlg('Data tidak ada!','Error');
                app.SegmentasiButton.Enable = "on";
            end
        end

        % Button pushed function: EkstraksiHasilButton
        function EkstraksiHasilButtonPushed(app, event)
                app.EkstraksiHasilButton.Enable ="off";
                I = app.a;
                save('target.mat','I');
                Ekstraksi;
                app.TextArea.Visible = "on";
                load('data.mat','ciri_data')
                app.TextArea.Value = sprintf('Metric               : %d \nEccentricity      : %d \nContrast           : %d \nCorrelation       : %d \nEnergy             : %d \nHomogeneity   : %d',ciri_data(1),ciri_data(2),ciri_data(3),ciri_data(4),ciri_data(5),ciri_data(6));
                delete('target.mat');
                app.HasilPencocokanButton.Enable ="on";
        end

        % Button pushed function: HasilPencocokanButton
        function HasilPencocokanButtonPushed(app, event)
            Pencocokan;
            load('hasil.mat','true_name');
            app.TextArea.Value = sprintf('Buah yang dideteksi adalah %s',true_name);
            delete('hasil.mat')
            app.HasilPencocokanButton.Enable ="off";
            app.HasilSalahButton.Enable ="on";
        end

        % Value changed function: HasilSalahButton
        function HasilSalahButtonValueChanged(app, event)
            value = app.HasilSalahButton.Value;
            if value == 1
                app.HasilSalahButton.Text="Batal";
                app.TulisNamaBuahEditField.Visible ="on";
                app.TulisNamaBuahEditField.Enable ="on";
                app.SimpanButton.Visible = "on";
                app.SimpanButton.Enable = "on";
            else
                app.HasilSalahButton.Text="Hasil Salah?";
                app.TulisNamaBuahEditField.Visible ="off";
                app.TulisNamaBuahEditField.Enable ="off";
                app.SimpanButton.Visible = "off";
                app.SimpanButton.Enable = "off";
            end
        end

        % Button pushed function: KeluarButton
        function KeluarButtonPushed(app, event)
            exit;
        end

        % Button pushed function: SimpanButton
        function SimpanButtonPushed(app, event)
            value = app.TulisNamaBuahEditField.Value + ".jpg";
            copyfile(app.file,value);
            movefile(value,"buah\");
            Database;
            msgbox("buah telah ditambahkan");
            app.HasilSalahButton.Value = 0;
            app.HasilSalahButton.Text="Hasil Salah?";
            app.HasilSalahButton.Enable = "off";
            app.TulisNamaBuahEditField.Visible ="off";
            app.TulisNamaBuahEditField.Enable ="off";
            app.SimpanButton.Visible = "off";
            app.SimpanButton.Enable = "off";
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {490, 490};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PilihGambarButton = uibutton(app.LeftPanel, 'state');
            app.PilihGambarButton.ValueChangedFcn = createCallbackFcn(app, @PilihGambarButtonValueChanged, true);
            app.PilihGambarButton.Text = 'Pilih Gambar';
            app.SegmentasiButton = uibutton(app.LeftPanel, 'push');
            app.SegmentasiButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentasiButtonPushed, true);
            app.SegmentasiButton.Enable = 'off';
            app.SegmentasiButton.Text = 'Segmentasi';
            app.EkstraksiHasilButton = uibutton(app.LeftPanel, 'push');
            app.EkstraksiHasilButton.ButtonPushedFcn = createCallbackFcn(app, @EkstraksiHasilButtonPushed, true);
            app.EkstraksiHasilButton.Enable = 'off';
            app.EkstraksiHasilButton.Text = 'Ekstraksi Hasil';
            app.HasilPencocokanButton = uibutton(app.LeftPanel, 'push');
            app.HasilPencocokanButton.ButtonPushedFcn = createCallbackFcn(app, @HasilPencocokanButtonPushed, true);
            app.HasilPencocokanButton.Enable = 'off';
            app.HasilPencocokanButton.Text = 'Hasil Pencocokan';
            app.TulisNamaBuahEditField = uieditfield(app.LeftPanel, 'text');
            app.TulisNamaBuahEditField.Enable = 'off';
            app.TulisNamaBuahEditField.Visible = 'off';
            app.TulisNamaBuahEditField.Placeholder = 'Silahkan tulis nama buah';
            app.KeluarButton = uibutton(app.LeftPanel, 'push');
            app.KeluarButton.ButtonPushedFcn = createCallbackFcn(app, @KeluarButtonPushed, true);
            app.KeluarButton.Text = 'Keluar';
            app.HasilSalahButton = uibutton(app.LeftPanel, 'state');
            app.HasilSalahButton.ValueChangedFcn = createCallbackFcn(app, @HasilSalahButtonValueChanged, true);
            app.HasilSalahButton.Enable = 'off';
            app.HasilSalahButton.Text = 'Hasil Salah?';
            app.SimpanButton = uibutton(app.LeftPanel, 'push');
            app.SimpanButton.ButtonPushedFcn = createCallbackFcn(app, @SimpanButtonPushed, true);
            app.SimpanButton.Enable = 'off';
            app.SimpanButton.Visible = 'off';
            app.SimpanButton.Text = 'Simpan';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Target Buah')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.TextArea = uitextarea(app.GridLayout2);
            app.TextArea.Editable = 'off';
            app.TextArea.Visible = 'off';
            app.TextArea.Value = {'Metric               :'; 'Eccentricity      :'; 'Contrast           :'; 'Correlation       :'; 'Energy             :'; 'Homogeneity   :'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end